classdef Ising_heatbath_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        CPButton         matlab.ui.control.Button
        H0Button         matlab.ui.control.Button
        HSlider          matlab.ui.control.Slider
        HSliderLabel     matlab.ui.control.Label
        Label            matlab.ui.control.Label
        resetButton      matlab.ui.control.Button
        tempSlider       matlab.ui.control.Slider
        tempSliderLabel  matlab.ui.control.Label
        startButton      matlab.ui.control.Button
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t = 0; % Description
        i_chk = 0; % Description
        beta = 100; % Description

        N = 128; 
        s; % = 2*(randi([0,1],N) - 0.5); % state (spins)
        mu = 1;
        H = 0;
        J = 1;   

        tm;
        op;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t = 0;
            app.i_chk = 0;
            app.s = 2*(randi([0,1],app.N) - 0.5); % state (spins)
            app.beta = 50;
            app.tempSliderLabel.Text = "temp. = " +string(app.beta);
            app.H = 0;
            app.HSliderLabel.Text = "H. = " +string(app.H);
            app.tm = [];
            app.op = [];
            app.Label.Text = "o.p. = " + string(mean(app.s,"all"));
            imagesc(app.UIAxes, app.s);

        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            if(app.startButton.Text=="start")
                app.startButton.Text="stop";
                app.i_chk = 0;
            else
                app.startButton.Text="start";
                app.i_chk = 1;                
            end

            while app.i_chk == 0
                for ss = 1:app.N*app.N

                    % heat bath method
                    i = randi(app.N);
                    j = randi(app.N);
                    % nearest neighbor of (i,j)
                    % (i1,j),(i2,j),(i,j1),(i,j2)
                    i1 = i - 1;
                    i2 = i + 1;
                    j1 = j - 1;
                    j2 = j + 1;
                    % periodic boundary condition
                    if i1 == 0
                        i1 = app.N;
                    elseif i2 == app.N+1
                        i2 = 1;
                    end
                    if j1 == 0
                        j1 = app.N;
                    elseif j2 == app.N+1
                        j2 = 1;
                    end
                    neighbor = app.s(i1,j) + app.s(i2,j) + app.s(i,j1) + app.s(i,j2);
                    E_local = - app.mu*app.H - app.J*neighbor;
                    E1 = -E_local;

                    bb = 8/app.beta;
 
                    pr = exp(-bb*E_local)/(exp(-bb*E_local)+exp(-bb*E1));
    
                    if pr > rand(1)
                        app.s(i,j) = 1;
                    else
                        app.s(i,j) = -1;                    
                    end
                end
            
                % visualize (movie)
                if rem(app.t,1)==0
                    imagesc(app.UIAxes, app.s, [-1 1]);
                    title(app.UIAxes,"heatbath method : mcs = "+string(app.t))
            
                    app.tm = [app.tm app.t];
                    app.op = [app.op mean(app.s,"all")];
                    plot(app.UIAxes2, app.tm, app.op);

                    app.Label.Text = "o.p. = " + string(mean(app.s,"all"));

            
                end

                app.t = app.t + 1;
                pause(0.05);
            end          
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: tempSlider
        function tempSliderValueChanged(app, event)
            app.beta = app.tempSlider.Value;
            app.tempSliderLabel.Text = "temp. =" +string(app.beta);            
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            app.startButton.Text="start";
            app.t = 0;
            app.i_chk = 1; 
            app.tm = [];
            app.op = [];
            app.s = 2*(randi([0,1],app.N) - 0.5); % state (spins)
            %clf;
            imagesc(app.UIAxes, app.s);
            app.Label.Text = "o.p. = " + string(mean(app.s,"all"));

            %cla(app.UIAxes);
            %title(app.UIAxes, app.t+"steps");
        end

        % Value changing function: tempSlider
        function tempSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tempSliderLabel.Text = "temp. =" +string(changingValue);                        
        end

        % Value changed function: HSlider
        function HSliderValueChanged(app, event)
            app.H = app.HSlider.Value;
            app.HSliderLabel.Text = "H. =" +string(app.H);
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            changingValue_H = event.Value;
            app.HSliderLabel.Text = "H. =" +string(changingValue_H);
        end

        % Button pushed function: H0Button
        function H0ButtonPushed(app, event)
            app.H = 0;
            app.HSlider.Value = 0;
            app.HSliderLabel.Text = "H. =" +string(app.H);            
        end

        % Button pushed function: CPButton
        function CPButtonPushed(app, event)
            app.H = 0;
            app.HSlider.Value = 0;
            app.HSliderLabel.Text = "H. =" +string(app.H);

            app.beta = 22.69;
            app.tempSlider.Value = 22.69;
            app.tempSliderLabel.Text = "temp. =" +string(app.beta);              
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XLim = [0 128];
            app.UIAxes.YLim = [0 128];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'order parameter')
            xlabel(app.UIAxes2, 'time step')
            app.UIAxes2.YLim = [-1 1];
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.tempSliderLabel = uilabel(app.UIFigure);
            app.tempSliderLabel.Text = 'temp';
            app.tempSlider = uislider(app.UIFigure);
            app.tempSlider.Limits = [0 50];
            app.tempSlider.MajorTicks = [0 10 20 30 40 50];
            app.tempSlider.ValueChangedFcn = createCallbackFcn(app, @tempSliderValueChanged, true);
            app.tempSlider.ValueChangingFcn = createCallbackFcn(app, @tempSliderValueChanging, true);
            app.tempSlider.MinorTicks = [];
            app.tempSlider.Value = 50;
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.Label = uilabel(app.UIFigure);
            app.HSliderLabel = uilabel(app.UIFigure);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.UIFigure);
            app.HSlider.Limits = [-10 10];
            app.HSlider.MajorTicks = [-10 -5 0 5 10];
            app.HSlider.MajorTickLabels = {'-10', '-5', '0', '5', '10'};
            app.HSlider.ValueChangedFcn = createCallbackFcn(app, @HSliderValueChanged, true);
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.H0Button = uibutton(app.UIFigure, 'push');
            app.H0Button.ButtonPushedFcn = createCallbackFcn(app, @H0ButtonPushed, true);
            app.H0Button.Text = 'H=0';
            app.CPButton = uibutton(app.UIFigure, 'push');
            app.CPButton.ButtonPushedFcn = createCallbackFcn(app, @CPButtonPushed, true);
            app.CPButton.Text = 'C.P.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ising_heatbath_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end